classdef newGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SpacialFrequencyDropDownLabel   matlab.ui.control.Label
        SpacialFrequencyDropDown        matlab.ui.control.DropDown
        EnterRotationEditFieldLabel     matlab.ui.control.Label
        EnterRotationEditField          matlab.ui.control.NumericEditField
        SetRotationButton               matlab.ui.control.Button
        GratingSettingsLabel            matlab.ui.control.Label
        SteeringSettingsLabel           matlab.ui.control.Label
        SteeringLengthDropDownLabel     matlab.ui.control.Label
        SteeringLengthDropDown          matlab.ui.control.DropDown
        SteeringOnOffDropDownLabel      matlab.ui.control.Label
        SteeringOnOffDropDown           matlab.ui.control.DropDown
        EnterSpeedEditFieldLabel        matlab.ui.control.Label
        EnterSpeedEditField             matlab.ui.control.NumericEditField
        SetSpeedButton                  matlab.ui.control.Button
        BranchNumberDropDownLabel       matlab.ui.control.Label
        BranchNumberDropDown            matlab.ui.control.DropDown
        BranchSettingsLabel             matlab.ui.control.Label
        GeneralSettingsLabel            matlab.ui.control.Label
        LogTextEditFieldLabel           matlab.ui.control.Label
        LogTextEditField                matlab.ui.control.EditField
        LogButton                       matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        debugEditFieldLabel             matlab.ui.control.Label
        debugEditField                  matlab.ui.control.EditField
        ChoiceEditField                 matlab.ui.control.EditField
        ChoiceLabel                     matlab.ui.control.Label
        EnterStartupInfoEditFieldLabel  matlab.ui.control.Label
        EnterStartupInfoEditField       matlab.ui.control.EditField
        Label                           matlab.ui.control.Label
        SendButton                      matlab.ui.control.Button
        RewardButton                    matlab.ui.control.Button
        GratingSideLabel                matlab.ui.control.Label
        GratingSideEditField            matlab.ui.control.NumericEditField
        SetStimulusRatioButton          matlab.ui.control.Button
        MovieModeSideEditFieldLabel     matlab.ui.control.Label
        MovieModeSideEditField          matlab.ui.control.NumericEditField
        SetSideRatioButton              matlab.ui.control.Button
        TrialNumberLabel                matlab.ui.control.Label
        trialNumberLabel                matlab.ui.control.Label
        LickCountLabel                  matlab.ui.control.Label
        LickCountNumber                 matlab.ui.control.Label
    end


    properties (Access = private)
        LinearMazeObject  % handle for LinearMaze class
    end


    methods (Access = private)

        % Code that executes after component creation
        function CallLinearMaze(app)
            %app.LinearMazeObject = LinearMaze([],app);%initialize linearMaze
            %app.LinearMazeObject.RecieveAppHandle(app);%send a handle to this app
            %this should disable everything 
            set(findall(app.UIFigure, '-property', 'enable'), 'enable', 'off');
            set(app.EnterStartupInfoEditField,'Enable','on');
            set(app.SendButton,'Enable','on');
            set(app.Label,'Enable','on');
            %clc; %clear command window
        end

        % Value changed function: SpacialFrequencyDropDown
        function SendtoLinearMaze_SpatFreq(app, event)
            %value = app.SpacialFrequencyDropDown.Value;
            value = find(strcmp(app.SpacialFrequencyDropDown.Items,app.SpacialFrequencyDropDown.Value)); %find index of value currently selected
            
            app.LinearMazeObject.stimThickness(value) %send change of spatial freq to linearMaze
            
        end

        % Button pushed function: SetRotationButton
        function SendtoLinearMaze_rotation(app, event)
            %get from rotation textbox
            %send value to textRotation
            rotation = app.EnterRotationEditField.Value;
            app.LinearMazeObject.textRotation(rotation);
        end

        % Button pushed function: StartButton
        function SendtoLinearMaze_start(app, event)
             app.LinearMazeObject.start();
        end

        % Button pushed function: StopButton
        function SendtoLinearMaze_stop(app, event)
            app.LinearMazeObject.stop();
        end

        % Button pushed function: ResetButton
        function SendtoLinearMaze_reset(app, event)
            app.LinearMazeObject.reset();
        end

        % Callback function
        function SendtoLinearMaze_GratingSide(app, event)
            %NO LONGER USE. USE: SendtoLinearMaze_setStimSide()
            
%             %(1random, 2left, 3right, 4off)
%             sideNum = find(strcmp(app.GratingSideDropDown.Items,app.GratingSideDropDown.Value)); %find index of value currently selected
%             
%             %app.GratingSideDropDown.Value;
%             app.LinearMazeObject.ManualGratingSide(sideNum);
        end

        % Button pushed function: SetSpeedButton
        function SendtoLinearMaze_speed(app, event)
            speed = app.EnterSpeedEditField.Value;
            app.LinearMazeObject.textSpeed(speed);
        end

        % Callback function
        function SendtoLinearMaze_MovieSteering(app, event)
            %NO LONGER USE. USE SendtoLinearMaze_setMovieSide().
%             sideNum = find(strcmp(app.MovieModeSideDropDown.Items,app.MovieModeSideDropDown.Value)); %find index of value currently selected
%             app.LinearMazeObject.ManualDirection(sideNum);
            
        end

        % Value changed function: BranchNumberDropDown
        function SendtoLinearMaze_BranchNum(app, event)
            
            branchNum = find(strcmp(app.BranchNumberDropDown.Items,app.BranchNumberDropDown.Value)); %find index of value currently selected
            app.LinearMazeObject.chooseBranch(branchNum);
        end

        % Button pushed function: LogButton
        function SendtoLinearMaze_Log(app, event)
            %logText = app.LogTextEditField.Value;
            app.LinearMazeObject.onLogButton();
        end

        % Value changed function: SteeringLengthDropDown
        function SendtoLinearMaze_steeringLength(app, event)
            value = find(strcmp(app.SteeringLengthDropDown.Items,app.SteeringLengthDropDown.Value)); %find index of value currently selected
            app.LinearMazeObject.ManualChoiceDistance(value);
        end

        % Close request function: UIFigure
        function deleteLinearMaze(app, event)
            try %try to delete this. there may be an error running linear maze so this would normally fail to execute in that case.
                app.LinearMazeObject.delete();
            catch   
            end
            
            
            delete(app);
            %clc;
            %clear;
            
            
        end

        % Button pushed function: SendButton
        function SendtoLinearMaze_startUpInfo(app, event)
            %varargin = app.EnterStartupInfoEditField.Value;
            %set(app.SendButton,'enable','off');
            if ~isempty(app.EnterStartupInfoEditField.Value)
                set(app.SendButton,'enable','off');
                drawnow;
                app.LinearMazeObject = LinearMaze(app, app.EnterStartupInfoEditField.Value);
            end
        end

        % Button pushed function: RewardButton
        function SendtoLinearMaze_reward(app, event)
            %send pulse to water pump
            app.LinearMazeObject.ManualReward();
        end

        % Button pushed function: SetStimulusRatioButton
        function SendtoLinearMaze_setStimSide(app, event)
            gratingNum = app.GratingSideEditField.Value;
            app.LinearMazeObject.ManualGratingSide(gratingNum);
        end

        % Button pushed function: SetSideRatioButton
        function SendtoLinearMaze_setMovieSide(app, event)
            sideNum = app.MovieModeSideEditField.Value;
            app.LinearMazeObject.ManualDirection(sideNum);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @deleteLinearMaze, true);
            app.SpacialFrequencyDropDownLabel = uilabel(app.UIFigure);
            app.SpacialFrequencyDropDownLabel.Text = 'Spacial Frequency';
            app.SpacialFrequencyDropDown = uidropdown(app.UIFigure);
            app.SpacialFrequencyDropDown.Items = {'Thick', 'Thin'};
            app.SpacialFrequencyDropDown.ValueChangedFcn = createCallbackFcn(app, @SendtoLinearMaze_SpatFreq, true);
            app.SpacialFrequencyDropDown.Value = 'Thick';
            app.EnterRotationEditFieldLabel = uilabel(app.UIFigure);
            app.EnterRotationEditFieldLabel.Text = 'Enter Rotation:';
            app.EnterRotationEditField = uieditfield(app.UIFigure, 'numeric');
            app.SetRotationButton = uibutton(app.UIFigure, 'push');
            app.SetRotationButton.ButtonPushedFcn = createCallbackFcn(app, @SendtoLinearMaze_rotation, true);
            app.SetRotationButton.Text = 'Set Rotation';
            app.GratingSettingsLabel = uilabel(app.UIFigure);
            app.GratingSettingsLabel.Text = 'Grating Settings:';
            app.SteeringSettingsLabel = uilabel(app.UIFigure);
            app.SteeringSettingsLabel.Text = 'Steering Settings:';
            app.SteeringLengthDropDownLabel = uilabel(app.UIFigure);
            app.SteeringLengthDropDownLabel.Text = 'Steering Length';
            app.SteeringLengthDropDown = uidropdown(app.UIFigure);
            app.SteeringLengthDropDown.Items = {'4/4', '3/4', '2/4', '1/4'};
            app.SteeringLengthDropDown.ValueChangedFcn = createCallbackFcn(app, @SendtoLinearMaze_steeringLength, true);
            app.SteeringLengthDropDown.Value = '4/4';
            app.SteeringOnOffDropDownLabel = uilabel(app.UIFigure);
            app.SteeringOnOffDropDownLabel.Text = 'Steering On/Off';
            app.SteeringOnOffDropDown = uidropdown(app.UIFigure);
            app.SteeringOnOffDropDown.Items = {'On (doesnt work)', 'Temp MovieMode'};
            app.SteeringOnOffDropDown.Value = 'On (doesnt work)';
            app.EnterSpeedEditFieldLabel = uilabel(app.UIFigure);
            app.EnterSpeedEditFieldLabel.Text = 'Enter Speed:';
            app.EnterSpeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnterSpeedEditField.Value = 25;
            app.SetSpeedButton = uibutton(app.UIFigure, 'push');
            app.SetSpeedButton.ButtonPushedFcn = createCallbackFcn(app, @SendtoLinearMaze_speed, true);
            app.SetSpeedButton.Text = 'Set Speed';
            app.BranchNumberDropDownLabel = uilabel(app.UIFigure);
            app.BranchNumberDropDownLabel.Text = 'Branch Number';
            app.BranchNumberDropDown = uidropdown(app.UIFigure);
            app.BranchNumberDropDown.Items = {'1', '2', '3'};
            app.BranchNumberDropDown.ValueChangedFcn = createCallbackFcn(app, @SendtoLinearMaze_BranchNum, true);
            app.BranchNumberDropDown.Value = '1';
            app.BranchSettingsLabel = uilabel(app.UIFigure);
            app.BranchSettingsLabel.Text = 'Branch Settings:';
            app.GeneralSettingsLabel = uilabel(app.UIFigure);
            app.GeneralSettingsLabel.Text = 'General Settings:';
            app.LogTextEditFieldLabel = uilabel(app.UIFigure);
            app.LogTextEditFieldLabel.Text = 'Log Text';
            app.LogTextEditField = uieditfield(app.UIFigure, 'text');
            app.LogButton = uibutton(app.UIFigure, 'push');
            app.LogButton.ButtonPushedFcn = createCallbackFcn(app, @SendtoLinearMaze_Log, true);
            app.LogButton.Text = 'Log Button';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @SendtoLinearMaze_reset, true);
            app.ResetButton.Text = 'Reset';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @SendtoLinearMaze_start, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @SendtoLinearMaze_stop, true);
            app.StopButton.Text = 'Stop';
            app.debugEditFieldLabel = uilabel(app.UIFigure);
            app.debugEditFieldLabel.Text = 'debug';
            app.debugEditField = uieditfield(app.UIFigure, 'text');
            app.debugEditField.Value = 'not ready';
            app.ChoiceEditField = uieditfield(app.UIFigure, 'text');
            app.ChoiceLabel = uilabel(app.UIFigure);
            app.ChoiceLabel.Text = 'Choice';
            app.EnterStartupInfoEditFieldLabel = uilabel(app.UIFigure);
            app.EnterStartupInfoEditFieldLabel.Text = 'Enter Startup Info';
            app.EnterStartupInfoEditField = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'com,com5,monitors, {192.168.0.111;0; 192.168.0.109;90; 192.168.0.110;-90},hardware,0/2';
            app.SendButton = uibutton(app.UIFigure, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendtoLinearMaze_startUpInfo, true);
            app.SendButton.Text = 'Send';
            app.RewardButton = uibutton(app.UIFigure, 'push');
            app.RewardButton.ButtonPushedFcn = createCallbackFcn(app, @SendtoLinearMaze_reward, true);
            app.RewardButton.Text = 'Reward';
            app.GratingSideLabel = uilabel(app.UIFigure);
            app.GratingSideLabel.Text = 'Grating Side:';
            app.GratingSideEditField = uieditfield(app.UIFigure, 'numeric');
            app.SetStimulusRatioButton = uibutton(app.UIFigure, 'push');
            app.SetStimulusRatioButton.ButtonPushedFcn = createCallbackFcn(app, @SendtoLinearMaze_setStimSide, true);
            app.SetStimulusRatioButton.Text = 'Set Stimulus Ratio';
            app.MovieModeSideEditFieldLabel = uilabel(app.UIFigure);
            app.MovieModeSideEditFieldLabel.Text = 'MovieMode Side';
            app.MovieModeSideEditField = uieditfield(app.UIFigure, 'numeric');
            app.SetSideRatioButton = uibutton(app.UIFigure, 'push');
            app.SetSideRatioButton.ButtonPushedFcn = createCallbackFcn(app, @SendtoLinearMaze_setMovieSide, true);
            app.SetSideRatioButton.Text = 'Set Side Ratio';
            app.TrialNumberLabel = uilabel(app.UIFigure);
            app.TrialNumberLabel.Text = 'Trial Number:';
            app.trialNumberLabel = uilabel(app.UIFigure);
            app.trialNumberLabel.Text = '1';
            app.LickCountLabel = uilabel(app.UIFigure);
            app.LickCountLabel.Text = 'Lick Count:';
            app.LickCountNumber = uilabel(app.UIFigure);
            app.LickCountNumber.Text = '0';
        end
    end

    methods (Access = public)

        % Construct app
        function app = newGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @CallLinearMaze)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end